classdef ColormapControl < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                matlab.ui.container.GridLayout
        EndColorPicker            matlab.ui.control.ColorPicker
        StartColorPicker          matlab.ui.control.ColorPicker
        CustomMapEndColorLabel    matlab.ui.control.Label
        CustomMapStartColorLabel  matlab.ui.control.Label
        ColormapPreview           matlab.ui.control.Image
        ColormapDropDown          matlab.ui.control.DropDown
        ColormapDropDownLabel     matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ColormapChanged % Fire when the Colormap property is interactively changed
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        ColormapName (1,1) string {mustBeMember(ColormapName, ["custom" "parula" "turbo" "hot" "cool" "winter" "spring" "summer" "autumn" "bone" "sky" "abyss"])} = "parula"; % Name of a prefab colormap
        EndColor (1,3) double {mustBeInRange(EndColor, 0,1)} = [1 0 0]; % Last color in custom colormap
        StartColor (1,3) double {mustBeInRange(StartColor, 0,1)} = [0.5 0 0]; % First color in a custom colormap
    end
    
    properties (Access = private)
        SuppressNotify (1,1) logical = false;
    end
    
    properties (Access = public)
        Colormap (256,3) double % Colormap that is selected
    end
    
    
    methods (Access = private)
        function rgbmap = interpclrs(app,clrs)
        % Linearly Interpolate between all colors in CLR out to 256 clrs
        
            space = 1:256;
        
            ox = floor(linspace(1,256,size(clrs,1)));
        
            rgbmap = [ interp1(ox,clrs(:,1)',space, 'pchip')
                       interp1(ox,clrs(:,2)',space, 'pchip')
                       interp1(ox,clrs(:,3)',space, 'pchip') ]';

            %rgbmap = hsv2rgb(hsvmap);
        end

        function updateMap(comp, event)
            mapname = comp.ColormapDropDown.Value;
            if mapname == "custom"
                map = comp.interpclrs([comp.StartColorPicker.Value
                                       comp.EndColorPicker.Value]);
            else
                map = eval(mapname+"(256)");
            end
            comp.Colormap = map;
            % Update components on property change.
            comp.StartColorPicker.Value = comp.StartColor;
            comp.EndColorPicker.Value = comp.EndColor;
            comp.ColormapDropDown.Value = comp.ColormapName;
            % Now update the image
            map = comp.Colormap;
            comp.ColormapPreview.ImageSource = reshape(map,1,256,3);
        end
        
        function results = propSet(comp)
            % Called when a property is set - update the controls
            comp.SuppressNotify = true;
            try
                comp.ColormapDropDown.Value = comp.ColormapName;
                comp.StartColorPicker.Value = comp.StartColor;
                comp.EndColorPicker.Value = comp.EndColor;
            end
            comp.SuppressNotify = false;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            addlistener(comp, 'ColormapName', 'PostSet', @(h,e)comp.propSet());
            addlistener(comp, 'StartColor', 'PostSet', @(h,e)comp.propSet());
            addlistener(comp, 'EndColor', 'PostSet', @(h,e)comp.propSet());

            comp.updateMap([]);
        end

        % Callback function: EndColorPicker, StartColorPicker
        function updateCustomColor(comp, event)
            % Change properties based on user interactions.
            comp.StartColor = comp.StartColorPicker.Value;
            comp.EndColor = comp.EndColorPicker.Value;
            % Then update the colormap
            updateMap(comp,event);
            if ~comp.SuppressNotify
                notify(comp, 'ColormapChanged');
            end
        end

        % Value changed function: ColormapDropDown
        function updateMapName(comp, event)
            value = comp.ColormapDropDown.Value;
            comp.ColormapName = value;
            updateMap(comp,event);
            if ~comp.SuppressNotify
               notify(comp, 'ColormapChanged');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Force colormap to be recomputed.
            updateMap(comp,[]);           
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 201 202];
            comp.GridLayout = uigridlayout(comp);
            comp.ColormapDropDownLabel = uilabel(comp.GridLayout);
            comp.ColormapDropDownLabel.Text = 'Colormap';
            comp.ColormapDropDown = uidropdown(comp.GridLayout);
            comp.ColormapDropDown.Items = {'custom', 'parula', 'turbo', 'hot', 'cool', 'winter', 'spring', 'summer', 'autumn', 'bone', 'sky', 'abyss'};
            comp.ColormapDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateMapName, true);
            comp.ColormapDropDown.Value = 'parula';
            comp.ColormapPreview = uiimage(comp.GridLayout);
            comp.CustomMapStartColorLabel = uilabel(comp.GridLayout);
            comp.CustomMapStartColorLabel.Text = 'Custom Map Start Color';
            comp.CustomMapEndColorLabel = uilabel(comp.GridLayout);
            comp.CustomMapEndColorLabel.Text = 'Custom Map End Color';
            comp.StartColorPicker = uicolorpicker(comp.GridLayout);
            comp.StartColorPicker.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateCustomColor, true);
            comp.EndColorPicker = uicolorpicker(comp.GridLayout);
            comp.EndColorPicker.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateCustomColor, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end