% description: % Copyright 2024 The MathWorks, Inc.
classdef pumpkindesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        PrefabsTab                    matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        ApplyPrefabDropDown           matlab.ui.control.DropDown
        ApplyPrefabDropDownLabel      matlab.ui.control.Label
        NewPrefabName                 matlab.ui.control.EditField
        AddNameEditFieldLabel         matlab.ui.control.Label
        AddToPrefabListButton         matlab.ui.control.Button
        PumpkinTab                    matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        DimpleDepthSlider             matlab.ui.control.Slider
        DimpleDepthLabel              matlab.ui.control.Label
        SecondaryBumpDepthSlider      matlab.ui.control.Slider
        SecondaryBumpDepthLabel       matlab.ui.control.Label
        BumpDepthSlider               matlab.ui.control.Slider
        BumpDepthSliderLabel          matlab.ui.control.Label
        ofBumpsSlider                 matlab.ui.control.Slider
        ofBumpsSliderLabel            matlab.ui.control.Label
        HeightSlider                  matlab.ui.control.Slider
        HeightSliderLabel             matlab.ui.control.Label
        ColorTab                      matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        ColormapControl               colorctrl.ColormapControl
        SpecklesTab                   matlab.ui.container.Tab
        GridLayout9                   matlab.ui.container.GridLayout
        ResolutionSlider              matlab.ui.control.Slider
        ResolutionSliderLabel         matlab.ui.control.Label
        SpeckleProminenceSlider       matlab.ui.control.Slider
        SpeckleProminenceSliderLabel  matlab.ui.control.Label
        StemTab                       matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        StemStyleDropDown             matlab.ui.control.DropDown
        StemStyleDropDownLabel        matlab.ui.control.Label
        StemColorPicker               matlab.ui.control.ColorPicker
        StemColorLabel                matlab.ui.control.Label
        StemDimensionsSlider          matlab.ui.control.RangeSlider
        StemDimensionsSliderLabel     matlab.ui.control.Label
        StemRadiusSlider              matlab.ui.control.RangeSlider
        StemRadiusSliderLabel         matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        TabGroup2                     matlab.ui.container.TabGroup
        PreviewTab                    matlab.ui.container.Tab
        GridLayout7                   matlab.ui.container.GridLayout
        PreviewPane                   matlab.ui.container.Panel
        AxesLayout                    matlab.ui.container.GridLayout
        pumpkinAx                     matlab.ui.control.UIAxes
        ScriptTab                     matlab.ui.container.Tab
        GridLayout8                   matlab.ui.container.GridLayout
        ConstantFoldingCheckBox       matlab.ui.control.CheckBox
        CopyButton                    matlab.ui.control.Button
        ScriptPreview                 matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pumpkinS  % Surface object drawing the pumpkin
        stemS % Surface object drawing the pumpkin's stem
        PrefabCache % Description
    end
    
    methods (Access = private)
        function applyPrefab(app, prefab)

            C = prefab.metapumpkin;
            S = cell2struct(C(2:2:end), string(C(1:2:end)),1);
            
            app.HeightSlider.Value = S.Height;
            app.ofBumpsSlider.Value = S.NumBumps;
            app.BumpDepthSlider.Value = S.BumpDepth;
            app.SecondaryBumpDepthSlider.Value = S.SecondaryBumpDepth;
            app.DimpleDepthSlider.Value = S.DimpleDepth;
            app.StemStyleDropDown.Value = S.StemStyle;
            app.StemRadiusSlider.Value = S.StemRadius;
            app.StemDimensionsSlider.Value = S.StemDims;
            app.SpeckleProminenceSlider.Value = S.SpeckleSize;
            app.StemColorPicker.Value = S.StemColor;
            
            app.ColormapControl.ColormapName = prefab.colormap;
            app.ColormapControl.StartColor = prefab.colors(1,:);
            app.ColormapControl.EndColor = prefab.colors(2,:);
        end
        function prefab = computePrefab(app)
            prefab.name = "tmp";
            prefab.metapumpkin = ...
               { "Height"
                 app.HeightSlider.Value
                 "NumBumps"
                 floor(app.ofBumpsSlider.Value)
                 "BumpDepth"
                 app.BumpDepthSlider.Value
                 "SecondaryBumpDepth"
                 app.SecondaryBumpDepthSlider.Value
                 "DimpleDepth"
                 app.DimpleDepthSlider.Value
                 "StemStyle"
                 app.StemStyleDropDown.Value
                 "StemRadius"
                 app.StemRadiusSlider.Value
                 "StemDims"
                 app.StemDimensionsSlider.Value
                 "StemColor"
                 app.StemColorPicker.Value
                 "SpeckleSize"
                 app.SpeckleProminenceSlider.Value
               };
            prefab.colormap = app.ColormapControl.ColormapName;
            prefab.colors = ...
                [ app.ColormapControl.StartColor
                  app.ColormapControl.EndColor ];
            if prefab.colormap ~= "custom"
                prefab.metapumpkin = [ prefab.metapumpkin
                     {"Colormap"    
                     prefab.colormap }];
            else
                prefab.metapumpkin = [ prefab.metapumpkin
                     {"Colormap"   
                     prefab.colors }];
            end
        end

        function updatePumpkin(app)
            prefab = computePrefab(app);

            % Script options change what kind of script is made.
            scriptopts = { "ConstantFolding"
                           app.ConstantFoldingCheckBox.Value 
                           "Resolution"
                           floor(app.ResolutionSlider.Value)};

            %% Update the script preview
            S = metapumpkin(prefab.metapumpkin{:}, scriptopts{:});
            app.ScriptPreview.Value = S;

            %% Generate the data
            rng(0); % For random speckles - keep stable
            [DATA,MAP] = metapumpkin(prefab.metapumpkin{:}, scriptopts{:});
            
            if isempty(app.pumpkinS)
                set(app.pumpkinAx, "visible","off","clipping","off",...
                    "Interactions", [rotateInteraction zoomInteraction],...
                    "DataAspectRatio",[1 1 1]);
                axtoolbar(app.pumpkinAx,{ "restoreview" "export"});
                app.pumpkinS = surface(app.pumpkinAx,[1 1;1 1],"FaceColor","interp",...
                    "EdgeColor","none","FaceLighting","gouraud");
                app.stemS = surface(app.pumpkinAx, [1 1; 1 1],"FaceColor","black",...
                    "EdgeColor","none","FaceLighting","flat");
                material([app.pumpkinS, app.stemS],[.6 .9 .3 2 .5])
                view(app.pumpkinAx,3);
                axis(app.pumpkinAx,[-1 1 -1 1 -1 1],"vis3d")
                enableDefaultInteractivity(app.pumpkinAx);
                camlight(app.pumpkinAx)
            end

            set(app.pumpkinS,"XData",DATA.X,"YData",DATA.Y,"ZData",DATA.Z,"CData",DATA.C);
            set(app.stemS,"XData",DATA.Xst,"YData",DATA.Yst,"ZData",DATA.Zst,"FaceColor",DATA.Cst);
            ph = app.HeightSlider.Value;
            dd = app.DimpleDepthSlider.Value;
            sd = app.StemDimensionsSlider.Value;
            pdd = ph-max(0,ph*dd);
            psh = pdd + sd(2);
            set(app.pumpkinAx,"ZLim",[-max(pdd,ph) max(ph,psh)]);
            colormap(app.pumpkinAx, MAP);

            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(fileparts(mfilename("fullpath")));
            app.updatePumpkin();
            if exist("metapumpkinprefabs.mat","file")
                S = load("metapumpkinprefabs.mat","PREFABS");
                app.PrefabCache = S.PREFABS;
                names = { app.PrefabCache.name };
                app.ApplyPrefabDropDown.Items = names;
                app.ApplyPrefabDropDown.Value = names{1};
                if isdeployed()
                    % Smaller resolution when online for performance
                    app.ResolutionSlider.Value = 100;
                end
                SelectPrefab(app,[]);
            end
        end

        % Callback function: BumpDepthSlider, ColormapControl, 
        % ...and 11 other components
        function ControlValueChanged(app, event)
            app.updatePumpkin();
        end

        % Value changing function: BumpDepthSlider, DimpleDepthSlider, 
        % ...and 7 other components
        function ControlValueChanging(app, event)
            event.Source.Value = event.Value;
            app.updatePumpkin();
        end

        % Button pushed function: AddToPrefabListButton
        function AddPrefab(app, event)
            prefab = computePrefab(app);
            prefab.name = app.NewPrefabName.Value;

            PREFABS = app.PrefabCache;
            if isempty(PREFABS)
                PREFABS = prefab;
            else
                names = { app.PrefabCache.name };
                mask = strcmp(prefab.name,names);
                if any(mask)
                    opt=uiconfirm(app.UIFigure,"Replace Prefab " + prefab.name + "?",...
                        "Replace Prefab?", ...
                        "Options", ["Yes","No"]);
                    switch lower(opt)
                        case "yes"
                            PREFABS(mask) = prefab;
                        case "no"
                    end
                else
                    PREFABS(end+1) = prefab;
                end
            end
            save("metapumpkinprefabs.mat","PREFABS");

            app.PrefabCache = PREFABS;
            names = { app.PrefabCache.name };
            app.ApplyPrefabDropDown.Items = names;
        end

        % Value changed function: ApplyPrefabDropDown
        function SelectPrefab(app, event)
            value = app.ApplyPrefabDropDown.Value;
            names = { app.PrefabCache.name };
            prefab = app.PrefabCache(strcmp(value,names));
            app.applyPrefab(prefab);
            app.NewPrefabName.Value = value;
            app.updatePumpkin();
        end

        % Button pushed function: CopyButton
        function CopyCode(app, event)
            bigstr = char(join(app.ScriptPreview.Value,newline));
            clipboard("copy",bigstr);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {312, 312};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {320, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout11);
            app.PrefabsTab = uitab(app.TabGroup);
            app.PrefabsTab.Title = 'Prefabs';
            app.GridLayout5 = uigridlayout(app.PrefabsTab);
            app.AddToPrefabListButton = uibutton(app.GridLayout5, 'push');
            app.AddToPrefabListButton.ButtonPushedFcn = createCallbackFcn(app, @AddPrefab, true);
            app.AddToPrefabListButton.Text = 'Add To Prefab List';
            app.AddNameEditFieldLabel = uilabel(app.GridLayout5);
            app.AddNameEditFieldLabel.Text = 'Add Name';
            app.NewPrefabName = uieditfield(app.GridLayout5, 'text');
            app.NewPrefabName.InputType = 'alphanumerics';
            app.ApplyPrefabDropDownLabel = uilabel(app.GridLayout5);
            app.ApplyPrefabDropDownLabel.Text = {'Apply'; 'Prefab'};
            app.ApplyPrefabDropDown = uidropdown(app.GridLayout5);
            app.ApplyPrefabDropDown.Items = {'1'};
            app.ApplyPrefabDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPrefab, true);
            app.ApplyPrefabDropDown.Value = '1';
            app.PumpkinTab = uitab(app.TabGroup);
            app.PumpkinTab.Title = 'Pumpkin';
            app.GridLayout2 = uigridlayout(app.PumpkinTab);
            app.HeightSliderLabel = uilabel(app.GridLayout2);
            app.HeightSliderLabel.Text = 'Height';
            app.HeightSlider = uislider(app.GridLayout2);
            app.HeightSlider.Limits = [0.2 2];
            app.HeightSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.HeightSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.HeightSlider.Value = 1;
            app.ofBumpsSliderLabel = uilabel(app.GridLayout2);
            app.ofBumpsSliderLabel.Text = '# of Bumps';
            app.ofBumpsSlider = uislider(app.GridLayout2);
            app.ofBumpsSlider.Limits = [0 20];
            app.ofBumpsSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.ofBumpsSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.ofBumpsSlider.Value = 10;
            app.BumpDepthSliderLabel = uilabel(app.GridLayout2);
            app.BumpDepthSliderLabel.Text = 'Bump Depth';
            app.BumpDepthSlider = uislider(app.GridLayout2);
            app.BumpDepthSlider.Limits = [0 0.2];
            app.BumpDepthSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.BumpDepthSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.BumpDepthSlider.Value = 0.1;
            app.SecondaryBumpDepthLabel = uilabel(app.GridLayout2);
            app.SecondaryBumpDepthLabel.Text = {'Secondary'; 'Bump Depth'};
            app.SecondaryBumpDepthSlider = uislider(app.GridLayout2);
            app.SecondaryBumpDepthSlider.Limits = [0 0.1];
            app.SecondaryBumpDepthSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.SecondaryBumpDepthSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.SecondaryBumpDepthSlider.Value = 0.02;
            app.DimpleDepthLabel = uilabel(app.GridLayout2);
            app.DimpleDepthLabel.Text = {'Dimple'; 'Depth'};
            app.DimpleDepthSlider = uislider(app.GridLayout2);
            app.DimpleDepthSlider.Limits = [-0.2 1];
            app.DimpleDepthSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.DimpleDepthSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.DimpleDepthSlider.Value = 0.2;
            app.ColorTab = uitab(app.TabGroup);
            app.ColorTab.Title = 'Color';
            app.GridLayout6 = uigridlayout(app.ColorTab);
            app.ColormapControl = colorctrl.ColormapControl(app.GridLayout6);
            app.SpecklesTab = uitab(app.TabGroup);
            app.SpecklesTab.Title = 'Speckles';
            app.GridLayout9 = uigridlayout(app.SpecklesTab);
            app.SpeckleProminenceSliderLabel = uilabel(app.GridLayout9);
            app.SpeckleProminenceSliderLabel.Text = 'Speckle Prominence';
            app.SpeckleProminenceSlider = uislider(app.GridLayout9);
            app.SpeckleProminenceSlider.Limits = [0 0.1];
            app.SpeckleProminenceSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.SpeckleProminenceSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.ResolutionSliderLabel = uilabel(app.GridLayout9);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.GridLayout9);
            app.ResolutionSlider.Limits = [100 400];
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.ResolutionSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.ResolutionSlider.Value = 200;
            app.StemTab = uitab(app.TabGroup);
            app.StemTab.Title = 'Stem';
            app.GridLayout3 = uigridlayout(app.StemTab);
            app.StemRadiusSliderLabel = uilabel(app.GridLayout3);
            app.StemRadiusSliderLabel.Text = 'Stem Radius';
            app.StemRadiusSlider = uislider(app.GridLayout3, 'range');
            app.StemRadiusSlider.Limits = [0 0.2];
            app.StemRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.StemRadiusSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.StemRadiusSlider.Step = 0.01;
            app.StemRadiusSlider.Value = [0.06 0.1];
            app.StemDimensionsSliderLabel = uilabel(app.GridLayout3);
            app.StemDimensionsSliderLabel.Text = 'Stem Dimensions';
            app.StemDimensionsSlider = uislider(app.GridLayout3, 'range');
            app.StemDimensionsSlider.Limits = [0 1];
            app.StemDimensionsSlider.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.StemDimensionsSlider.ValueChangingFcn = createCallbackFcn(app, @ControlValueChanging, true);
            app.StemDimensionsSlider.Step = 0.01;
            app.StemDimensionsSlider.Value = [0.4 0.5];
            app.StemColorLabel = uilabel(app.GridLayout3);
            app.StemColorLabel.Text = 'Stem Color';
            app.StemColorPicker = uicolorpicker(app.GridLayout3);
            app.StemColorPicker.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.StemStyleDropDownLabel = uilabel(app.GridLayout3);
            app.StemStyleDropDownLabel.Text = 'Stem Style';
            app.StemStyleDropDown = uidropdown(app.GridLayout3);
            app.StemStyleDropDown.Items = {'Complex', 'Simple'};
            app.StemStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.StemStyleDropDown.Value = 'Complex';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout10 = uigridlayout(app.RightPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout10);
            app.PreviewTab = uitab(app.TabGroup2);
            app.PreviewTab.Title = 'Preview';
            app.GridLayout7 = uigridlayout(app.PreviewTab);
            app.PreviewPane = uipanel(app.GridLayout7);
            app.AxesLayout = uigridlayout(app.PreviewPane);
            app.pumpkinAx = uiaxes(app.AxesLayout);
            app.pumpkinAx.XTick = [];
            app.pumpkinAx.YTick = [];
            app.ScriptTab = uitab(app.TabGroup2);
            app.ScriptTab.Title = 'Script';
            app.GridLayout8 = uigridlayout(app.ScriptTab);
            app.ScriptPreview = uitextarea(app.GridLayout8);
            app.ScriptPreview.Editable = 'off';
            app.CopyButton = uibutton(app.GridLayout8, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyCode, true);
            app.CopyButton.Text = 'Copy';
            app.ConstantFoldingCheckBox = uicheckbox(app.GridLayout8);
            app.ConstantFoldingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlValueChanged, true);
            app.ConstantFoldingCheckBox.Text = {'Constant'; 'Folding'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pumpkindesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end